classdef TokaLab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        ContactusforbugslinkbelowLabel  matlab.ui.control.Label
        TokaLabappalphaversionLabel     matlab.ui.control.Label
        Button_Update                   matlab.ui.control.Button
        Button_Initialise               matlab.ui.control.Button
        SelecttheTokamakDropDown        matlab.ui.control.DropDown
        SelecttheTokamakDropDownLabel   matlab.ui.control.Label
        Image_TokaLab_banner            matlab.ui.control.Image
        a                               matlab.ui.control.Spinner
        MinorRadiusamSpinnerLabel       matlab.ui.control.Label
        Zmag                            matlab.ui.control.Spinner
        MagneticAxisZpositionmSpinnerLabel  matlab.ui.control.Label
        MagneticMajorRadiusRmSpinner    matlab.ui.control.Spinner
        MagneticMajorRadiusRmSpinnerLabel  matlab.ui.control.Label
        TargetSeparatrixParametersLabel  matlab.ui.control.Label
        gp2Spinner                      matlab.ui.control.Spinner
        gp2SpinnerLabel                 matlab.ui.control.Label
        gp1Spinner                      matlab.ui.control.Spinner
        gp1SpinnerLabel                 matlab.ui.control.Label
        gn2Spinner                      matlab.ui.control.Spinner
        gn2SpinnerLabel                 matlab.ui.control.Label
        gn1Spinner                      matlab.ui.control.Spinner
        gn1SpinnerLabel                 matlab.ui.control.Label
        d2Spinner                       matlab.ui.control.Spinner
        d2SpinnerLabel                  matlab.ui.control.Label
        d1Spinner                       matlab.ui.control.Spinner
        d1SpinnerLabel                  matlab.ui.control.Label
        k2Spinner                       matlab.ui.control.Spinner
        k2SpinnerLabel                  matlab.ui.control.Label
        k1Spinner                       matlab.ui.control.Spinner
        k1SpinnerLabel                  matlab.ui.control.Label
        gammabottomrightLabel           matlab.ui.control.Label
        gammabottomleftLabel            matlab.ui.control.Label
        gammauprightLabel               matlab.ui.control.Label
        gammaupleftLabel                matlab.ui.control.Label
        BottomTriangularityLabel        matlab.ui.control.Label
        UpperTriangularityLabel         matlab.ui.control.Label
        BottomElongationLabel_2         matlab.ui.control.Label
        UpperElongationLabel_2          matlab.ui.control.Label
        Hyperlink_LinkedIn              matlab.ui.control.Hyperlink
        Hyperlink_Mathwork_FileExchange  matlab.ui.control.Hyperlink
        Hyperlink_GitHub                matlab.ui.control.Hyperlink
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SeparatrixWall                  matlab.ui.control.Lamp
        SeparatrixOutsideVesselLampLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        GSsolverparametersLabel         matlab.ui.control.Label
        PlasmaandProfileParametersLabel  matlab.ui.control.Label
        Check_GSsolver                  matlab.ui.control.CheckBox
        IterationSpinner                matlab.ui.control.Spinner
        IterationSpinnerLabel           matlab.ui.control.Label
        na_2Spinner                     matlab.ui.control.Spinner
        na_2SpinnerLabel                matlab.ui.control.Label
        na_1Spinner                     matlab.ui.control.Spinner
        na_1SpinnerLabel                matlab.ui.control.Label
        n_s1e19Spinner                  matlab.ui.control.Spinner
        n_s1e19SpinnerLabel             matlab.ui.control.Label
        n_01e19Spinner                  matlab.ui.control.Spinner
        n_01e19SpinnerLabel             matlab.ui.control.Label
        J_ta_2Spinner                   matlab.ui.control.Spinner
        J_ta_2SpinnerLabel              matlab.ui.control.Label
        J_ta_1Spinner                   matlab.ui.control.Spinner
        J_ta_1SpinnerLabel              matlab.ui.control.Label
        beta_0Spinner                   matlab.ui.control.Spinner
        beta_0SpinnerLabel              matlab.ui.control.Label
        I_pMASpinner                    matlab.ui.control.Spinner
        I_pMASpinnerLabel               matlab.ui.control.Label
        B_tTSpinner                     matlab.ui.control.Spinner
        B_tTSpinnerLabel                matlab.ui.control.Label
        PlotField                       matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        ProfilesTab                     matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        PlotProfile3                    matlab.ui.control.UIAxes
        PlotProfile2                    matlab.ui.control.UIAxes
        PlotProfile1                    matlab.ui.control.UIAxes
        DiagnosticsTab                  matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        BolometerCheckBox               matlab.ui.control.CheckBox
        InterferometerPolarimeterCheckBox  matlab.ui.control.CheckBox
        ThomsonScatteringCheckBox       matlab.ui.control.CheckBox
        FluxLoopsCheckBox               matlab.ui.control.CheckBox
        SaddleCoilsCheckBox             matlab.ui.control.CheckBox
        PickUpCoilsCheckBox             matlab.ui.control.CheckBox
        DiagnosticGeometry              matlab.ui.control.UIAxes
        MeasurementsTab                 matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        DiagnosticNoiseSlider_bot       matlab.ui.control.Slider
        DiagnosticNoiseSlider_2Label    matlab.ui.control.Label
        DiagnosticNoiseSlider_top       matlab.ui.control.Slider
        DiagnosticNoiseLabel            matlab.ui.control.Label
        BotFigureDropDown               matlab.ui.control.DropDown
        BottomFigureDropDownLabel       matlab.ui.control.Label
        TopFigureDropDown               matlab.ui.control.DropDown
        TopFigureDropDownLabel          matlab.ui.control.Label
        FigureMeasBot                   matlab.ui.control.UIAxes
        FigureMeasTop                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        tok                 % tokamak class
        geo                 % geometry class
        equi                % equilibrium class

        PickUp              % Pick Up coils
        FluxLoops           % Flux Loops
        SaddleCoils         % Saddle Loops
        TS                  % Thomson Scattering
        IntPol              % Interferometer Polarimeter
        Bolo                % Bolometry

    end

    methods (Access = private)

        function function_plotfield(app)

            Field_1 = strsplit(app.DropDown.Value);
            Field_1 = Field_1{1};
            Y = app.equi.(Field_1);
            hold(app.PlotField, 'off')
            contourf(app.PlotField,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.(Field_1),30,'LineStyle','none')
            colormap(app.PlotField,'Jet')
            colorbar(app.PlotField)
            clim(app.PlotField,[min(Y(app.geo.wall.inside),[],'all') max(Y(app.geo.wall.inside),[],'all')])
            hold(app.PlotField, 'on')
            fill(app.PlotField,app.geo.wall.solid_R,app.geo.wall.solid_Z,[200 200 200]/255,'LineStyle','none')
            contour(app.PlotField,app.equi.geo.grid.Rg,app.equi.geo.grid.Zg,app.equi.psi_n,[0 0.25 0.5 0.75 0.9 0.95 0.99 1 1.01],'w')
            plot(app.PlotField,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            plot(app.PlotField,app.equi.LCFS.R,app.equi.LCFS.Z,'-w','LineWidth',2)
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])
            title(app.PlotField,"GS solution - " + app.DropDown.Value)

            drawnow

        end

        function function_plotprofiles(app)

            app.equi = app.equi.evaluate_profiles_1D();
            hold(app.PlotProfile1,'off')
            plot(app.PlotProfile1,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.ne,...
                "LineWidth",2)
            hold(app.PlotProfile1,'on')
            plot(app.PlotProfile1,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.ni,...
                "LineWidth",2)
            xlabel(app.PlotProfile1,"\psi_n [arb.units]")
            ylabel(app.PlotProfile1,"n [m^{-3}]")
            axis(app.PlotProfile1,"normal")
            xlim(app.PlotProfile1,[0 1])
            ylim(app.PlotProfile1,[0 inf])
            grid(app.PlotProfile1,"on")
            grid(app.PlotProfile1,"minor")
            legend(app.PlotProfile1,"electron","ion")

            hold(app.PlotProfile2,'off')
            plot(app.PlotProfile2,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.Te,...
                "LineWidth",2)
            hold(app.PlotProfile2,'on')
            plot(app.PlotProfile2,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.Ti,...
                "LineWidth",2)
            xlabel(app.PlotProfile2,"\psi_n [arb.units]")
            ylabel(app.PlotProfile2,"T [eV]")
            axis(app.PlotProfile2,"normal")
            xlim(app.PlotProfile2,[0 1])
            ylim(app.PlotProfile2,[0 inf])
            grid(app.PlotProfile2,"on")
            grid(app.PlotProfile2,"minor")
            legend(app.PlotProfile2,"electron","ion")

            hold(app.PlotProfile3,'off')
            plot(app.PlotProfile3,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.pe,...
                "LineWidth",2)
            hold(app.PlotProfile3,'on')
            plot(app.PlotProfile3,app.equi.profiles_1D.psi_n,app.equi.profiles_1D.pi,...
                "LineWidth",2)
            xlabel(app.PlotProfile3,"\psi_n [arb.units]")
            ylabel(app.PlotProfile3,"p [Pa]")
            axis(app.PlotProfile3,"normal")
            xlim(app.PlotProfile3,[0 1])
            ylim(app.PlotProfile3,[0 inf])
            grid(app.PlotProfile3,"on")
            grid(app.PlotProfile3,"minor")
            legend(app.PlotProfile3,"electron","ion")

            drawnow

        end

        function function_plotdiagnostics(app)

            %% Diagnostic geometry
            Legend = "wall";

            hold(app.DiagnosticGeometry,'off')
            fill(app.DiagnosticGeometry,app.geo.wall.solid_R,app.geo.wall.solid_Z,[200 200 200]/255,'LineStyle','none')
            hold(app.DiagnosticGeometry,'on')
            if app.PickUpCoilsCheckBox.Value
                plot(app.DiagnosticGeometry,app.PickUp.R,app.PickUp.Z,'.b',"MarkerSize",16)
                Legend = [Legend; "Pick Ups"];
            end
            if app.SaddleCoilsCheckBox.Value
                plot(app.DiagnosticGeometry,[app.SaddleCoils.R1; app.SaddleCoils.R2],...
                    [app.SaddleCoils.Z1; app.SaddleCoils.Z2],'.-','MarkerSize',16,"Color","#7E2F8E")
                Legend = [Legend; "Saddle Coils"];
            end
            if app.FluxLoopsCheckBox.Value
                plot(app.DiagnosticGeometry,app.FluxLoops.R,app.FluxLoops.Z,'sg','LineWidth',1.6,"MarkerSize",10)
                Legend = [Legend; "Flux Loops"];
            end
            if app.ThomsonScatteringCheckBox.Value
                plot(app.DiagnosticGeometry,app.TS.R,app.TS.Z,'.r')
                Legend = [Legend; "Thomson Scattering"];
            end
            if app.InterferometerPolarimeterCheckBox.Value
                plot(app.DiagnosticGeometry,[app.IntPol.R_in';app.IntPol.R_out'],...
                    [app.IntPol.Z_in';app.IntPol.Z_out'],...
                    '-','MarkerSize',16,"Color","#A2142F")
                Legend = [Legend; "Interferometer-Polarimeter"];
            end
            if app.BolometerCheckBox.Value
                [Z, ~, iZ] = unique(app.Bolo.Z_in);
                groups = arrayfun(@(k) find(iZ == k), 1:numel(Z), 'UniformOutput', false);

                for i = 1: length(groups)
                    plot(app.DiagnosticGeometry,[app.Bolo.R_in(groups{i}) app.Bolo.R_end(groups{i})]',...
                        [app.Bolo.Z_in(groups{i}) app.Bolo.Z_end(groups{i})]', '-k', 'LineWidth', 1)
                end
            end
            % legend(app.DiagnosticGeometry,Legend)
            grid(app.DiagnosticGeometry,"on")
            grid(app.DiagnosticGeometry,"minor")
            axis(app.DiagnosticGeometry,"equal")
            xlim(app.DiagnosticGeometry,[min(app.geo.R) max(app.geo.R)])
            ylim(app.DiagnosticGeometry,[min(app.geo.Z) max(app.geo.Z)])

        end

        function function_Diagnostics_Measurements(app)

            %% Diagnostic Measurements - Top Figure
            if app.TopFigureDropDown.Value == "Pick Up Coils"
                app.PickUp.config.noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.PickUp = app.PickUp.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.PickUp.ideal.B,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.PickUp.B,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"B ["+app.PickUp.unit+"]")
            elseif  app.TopFigureDropDown.Value == "Saddle Loops"
                app.SaddleCoils.config.noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.SaddleCoils = app.SaddleCoils.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.SaddleCoils.ideal.Dpsi,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.SaddleCoils.Dpsi,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"\Delta \psi ["+app.SaddleCoils.unit+"]")
            elseif  app.TopFigureDropDown.Value == "Flux Loops"
                app.FluxLoops.config.noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.FluxLoops = app.FluxLoops.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.FluxLoops.ideal.psi,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.FluxLoops.psi,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"\psi ["+app.FluxLoops.unit+"]")
            elseif  app.TopFigureDropDown.Value == "Thomson Scattering (Density)"
                app.TS.config.ne_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.TS = app.TS.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.TS.ideal.ne,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.TS.ne,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"n_e ["+app.TS.unit_ne+"]")
            elseif  app.TopFigureDropDown.Value == "Thomson Scattering (Temperature)"
                app.TS.config.Te_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.TS = app.TS.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.TS.ideal.Te,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.TS.Te,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"T_e ["+app.TS.unit_Te+"]")
            elseif  app.TopFigureDropDown.Value == "Interferometer"
                app.IntPol.config.LID_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.IntPol = app.IntPol.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.IntPol.ideal.LIDh,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.IntPol.LIDh,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"LID ["+app.IntPol.unit_LID+"]")
            elseif  app.TopFigureDropDown.Value == "Polarimeter (Faraday Rotation)"
                app.IntPol.config.FAR_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.IntPol = app.IntPol.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.IntPol.ideal.FARh,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.IntPol.FARh,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"Far ["+app.IntPol.unit_FAR+"]")
             elseif  app.TopFigureDropDown.Value == "Polarimeter (Cotton Mouton)"
                app.IntPol.config.CM_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_top.Value/100;
                app.IntPol = app.IntPol.measure(app.equi);
                hold(app.FigureMeasTop,'off')
                plot(app.FigureMeasTop,app.IntPol.ideal.CMh,'.b','markersize',16)
                hold(app.FigureMeasTop,'on')
                plot(app.FigureMeasTop,app.IntPol.CMh,'or','linewidth',1.2)
                grid(app.FigureMeasTop,"on")
                grid(app.FigureMeasTop,"minor")
                xlabel(app.FigureMeasTop,"Channel")
                ylabel(app.FigureMeasTop,"CM ["+app.IntPol.unit_FAR+"]")
            end

            %% Diagnostic Measurements - Bottom Figure
            if app.BotFigureDropDown.Value == "Pick Up Coils"
                app.PickUp.config.noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.PickUp = app.PickUp.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.PickUp.ideal.B,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.PickUp.B,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"B ["+app.PickUp.unit+"]")
            elseif  app.BotFigureDropDown.Value == "Saddle Loops"
                app.SaddleCoils.config.noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.SaddleCoils = app.SaddleCoils.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.SaddleCoils.ideal.Dpsi,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.SaddleCoils.Dpsi,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"\Delta \psi ["+app.SaddleCoils.unit+"]")
            elseif  app.BotFigureDropDown.Value == "Flux Loops"
                app.FluxLoops.config.noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.FluxLoops = app.FluxLoops.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.FluxLoops.ideal.psi,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.FluxLoops.psi,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"\psi ["+app.FluxLoops.unit+"]")
            elseif  app.BotFigureDropDown.Value == "Thomson Scattering (Density)"
                app.TS.config.ne_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.TS = app.TS.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.TS.ideal.ne,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.TS.ne,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"n_e ["+app.TS.unit_ne+"]")
            elseif  app.BotFigureDropDown.Value == "Thomson Scattering (Temperature)"
                app.TS.config.Te_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.TS = app.TS.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.TS.ideal.Te,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.TS.Te,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"T_e ["+app.TS.unit_Te+"]")
            elseif  app.BotFigureDropDown.Value == "Interferometer"
                app.IntPol.config.LID_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.IntPol = app.IntPol.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.IntPol.ideal.LIDh,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.IntPol.LIDh,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"LID ["+app.IntPol.unit_LID+"]")
            elseif  app.BotFigureDropDown.Value == "Polarimeter (Faraday Rotation)"
                app.IntPol.config.FAR_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.IntPol = app.IntPol.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.IntPol.ideal.FARh,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.IntPol.FARh,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"Faraday ["+app.IntPol.unit_FAR+"]")
             elseif  app.BotFigureDropDown.Value == "Polarimeter (Cotton Mouton)"
                app.IntPol.config.CM_noise_random_proportional_intensity = app.DiagnosticNoiseSlider_bot.Value/100;
                app.IntPol = app.IntPol.measure(app.equi);
                hold(app.FigureMeasBot,'off')
                plot(app.FigureMeasBot,app.IntPol.ideal.CMh,'.b','markersize',16)
                hold(app.FigureMeasBot,'on')
                plot(app.FigureMeasBot,app.IntPol.CMh,'or','linewidth',1.2)
                grid(app.FigureMeasBot,"on")
                grid(app.FigureMeasBot,"minor")
                xlabel(app.FigureMeasBot,"Channel")
                ylabel(app.FigureMeasBot,"CM ["+app.IntPol.unit_FAR+"]")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Callback_StartUp(app)
 
            machine = app.SelecttheTokamakDropDown.Value;
            VirtualLab_init(machine)

            % tokamak class
            clear app.tok
            app.tok = tokamak;
            app.tok = app.tok.machine_upload(machine);
            app.tok = app.tok.scenario_upload(1,1);
            app.tok = app.tok.kinetic_upload();

            % geometry class
            clear app.geo
            app.geo = geometry;
            app.geo = app.geo.import_geometry(app.tok);
            app.geo = app.geo.build_geometry();
            app.geo = app.geo.inside_wall();

            % equilibrium class
            clear app.equi
            app.equi = equilibrium();
            app.equi = app.equi.import_configuration(app.geo,app.tok.config);
            app.equi = app.equi.import_classes();

            %% Plot section

            hold(app.PlotField,"off")
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])

            %% wall solid
            Rm = min(app.geo.R,[],'all');
            RM = max(app.geo.R,[],'all');
            Zm = min(app.geo.Z,[],'all');
            ZM = max(app.geo.Z,[],'all');

            app.geo.wall.solid_R = [Rm Rm RM RM RM RM Rm Rm Rm app.geo.wall.R];
            app.geo.wall.solid_Z = [Zm Zm Zm Zm ZM ZM ZM ZM Zm app.geo.wall.Z];

            %% Update values on spinner

            % Separatrix Spinner

            app.MagneticMajorRadiusRmSpinner.Value = app.equi.config.separatrix.R0;
            app.Zmag.Value = app.equi.config.separatrix.Z0;
            app.a.Value = app.equi.config.separatrix.a;

            app.k1Spinner.Value = app.equi.config.separatrix.k1;
            app.k2Spinner.Value = app.equi.config.separatrix.k2;
            app.d1Spinner.Value = app.equi.config.separatrix.d1;
            app.d2Spinner.Value = app.equi.config.separatrix.d2;

            app.gn1Spinner.Value = app.equi.config.separatrix.gamma_n_1*180/pi;
            app.gn2Spinner.Value = app.equi.config.separatrix.gamma_n_2*180/pi;
            app.gp1Spinner.Value = app.equi.config.separatrix.gamma_p_1*180/pi;
            app.gp2Spinner.Value = app.equi.config.separatrix.gamma_p_2*180/pi;

            % Kinetic / Profile spinner
            app.B_tTSpinner.Value = app.equi.config.toroidal_current.Bt;
            app.I_pMASpinner.Value = app.equi.config.toroidal_current.Ip/1e6;
            app.J_ta_1Spinner.Value = app.equi.config.toroidal_current.alpha_1;
            app.J_ta_2Spinner.Value = app.equi.config.toroidal_current.alpha_2;
            app.beta_0Spinner.Value = app.equi.config.toroidal_current.beta_0;

            app.n_01e19Spinner.Value = app.equi.config.kinetic.n0/1e19;
            app.n_s1e19Spinner.Value = app.equi.config.kinetic.nsep/1e19;
            app.na_1Spinner.Value = app.equi.config.kinetic.a1;
            app.na_2Spinner.Value = app.equi.config.kinetic.a2;

            %% Solve GS
            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
            app.equi.config.GSsolver.Plotting = 0;
            app.equi = app.equi.solve_equilibrium_dimless();
            app.equi = app.equi.equi_pp2();
            app.equi = app.equi.compute_profiles();

            %% Plot
            function_plotfield(app)
            function_plotprofiles(app)

            %% Diagnostics
            app.PickUp = Diag_PickUpCoils();
            app.PickUp = app.PickUp.Upload(1,machine);
            app.PickUp = app.PickUp.measure(app.equi);

            app.FluxLoops = Diag_FluxLoops();
            app.FluxLoops = app.FluxLoops.Upload(1,machine);

            app.SaddleCoils = Diag_SaddleCoils();
            app.SaddleCoils = app.SaddleCoils.Upload(1,machine);

            app.TS = Diag_ThomsonScattering();
            app.TS = app.TS.Upload(1,machine);

            app.IntPol = Diag_InterferometerPolarimeter();
            app.IntPol = app.IntPol.Upload(1,machine);

            app.Bolo = Diag_Bolo();
            app.Bolo = app.Bolo.Upload(1,machine);

            %% Plot Diagnostics
            function_plotdiagnostics(app)
            function_Diagnostics_Measurements(app)

            %% Touching Wall
            if app.equi.separatrix.touching_wall == 1
                app.SeparatrixWall.Color = [1 0 0];
            else
                app.SeparatrixWall.Color = [0.1 0.1 0.1];
            end


        end

        % Callback function: Button_Initialise, SelecttheTokamakDropDown
        function Callback_Initialise(app, event)

            machine = app.SelecttheTokamakDropDown.Value;

            % tokamak class
            clear app.tok
            app.tok = tokamak;
            app.tok = app.tok.machine_upload(machine);
            app.tok = app.tok.scenario_upload(1,1);
            app.tok = app.tok.kinetic_upload();

            % geometry class
            clear app.geo
            app.geo = geometry;
            app.geo = app.geo.import_geometry(app.tok);
            app.geo = app.geo.build_geometry();
            app.geo = app.geo.inside_wall();

            clear app.equi
            app.equi = equilibrium();
            app.equi = app.equi.import_configuration(app.geo,app.tok.config);
            app.equi = app.equi.import_classes();

            %% Plot section

            hold(app.PlotField,"off")
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])

            %% wall solid
            Rm = min(app.geo.R,[],'all');
            RM = max(app.geo.R,[],'all');
            Zm = min(app.geo.Z,[],'all');
            ZM = max(app.geo.Z,[],'all');

            app.geo.wall.solid_R = [Rm Rm RM RM RM RM Rm Rm Rm app.geo.wall.R];
            app.geo.wall.solid_Z = [Zm Zm Zm Zm ZM ZM ZM ZM Zm app.geo.wall.Z];

            %% Update values on spinner

            % Separatrix Spinner

            app.MagneticMajorRadiusRmSpinner.Value = app.equi.config.separatrix.R0;
            app.Zmag.Value = app.equi.config.separatrix.Z0;
            app.a.Value = app.equi.config.separatrix.a;

            app.k1Spinner.Value = app.equi.config.separatrix.k1;
            app.k2Spinner.Value = app.equi.config.separatrix.k2;
            app.d1Spinner.Value = app.equi.config.separatrix.d1;
            app.d2Spinner.Value = app.equi.config.separatrix.d2;

            app.gn1Spinner.Value = app.equi.config.separatrix.gamma_n_1*180/pi;
            app.gn2Spinner.Value = app.equi.config.separatrix.gamma_n_2*180/pi;
            app.gp1Spinner.Value = app.equi.config.separatrix.gamma_p_1*180/pi;
            app.gp2Spinner.Value = app.equi.config.separatrix.gamma_p_2*180/pi;

            % Kinetic / Profile spinner
            app.B_tTSpinner.Value = app.equi.config.toroidal_current.Bt;
            app.I_pMASpinner.Value = app.equi.config.toroidal_current.Ip/1e6;
            app.J_ta_1Spinner.Value = app.equi.config.toroidal_current.alpha_1;
            app.J_ta_2Spinner.Value = app.equi.config.toroidal_current.alpha_2;
            app.beta_0Spinner.Value = app.equi.config.toroidal_current.beta_0;

            app.n_01e19Spinner.Value = app.equi.config.kinetic.n0/1e19;
            app.n_s1e19Spinner.Value = app.equi.config.kinetic.nsep/1e19;
            app.na_1Spinner.Value = app.equi.config.kinetic.a1;
            app.na_2Spinner.Value = app.equi.config.kinetic.a2;

            %% Solve GS
            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
            app.equi.config.GSsolver.Plotting = 0;
            app.equi = app.equi.solve_equilibrium_dimless();
            app.equi = app.equi.equi_pp2();
            app.equi = app.equi.compute_profiles();

            %% Plot
            function_plotfield(app)
            function_plotprofiles(app)

            %% Diagnostics
            app.PickUp = Diag_PickUpCoils();
            app.PickUp = app.PickUp.Upload(1,machine);
            app.PickUp = app.PickUp.measure(app.equi);

            app.FluxLoops = Diag_FluxLoops();
            app.FluxLoops = app.FluxLoops.Upload(1,machine);

            app.SaddleCoils = Diag_SaddleCoils();
            app.SaddleCoils = app.SaddleCoils.Upload(1,machine);

            app.TS = Diag_ThomsonScattering();
            app.TS = app.TS.Upload(1,machine);

            app.IntPol = Diag_InterferometerPolarimeter();
            app.IntPol = app.IntPol.Upload(1,machine);

            app.Bolo = Diag_Bolo();
            app.Bolo = app.Bolo.Upload(1,machine);

            %% Plot Diagnostics
            function_plotdiagnostics(app)
            function_Diagnostics_Measurements(app)

            %% Touching Wall
            if app.equi.separatrix.touching_wall == 1
                app.SeparatrixWall.Color = [1 0 0];
            else
                app.SeparatrixWall.Color = [0.1 0.1 0.1];
            end


        end

        % Callback function: B_tTSpinner, Button_Update, Check_GSsolver, 
        % ...and 20 other components
        function Callback_Update(app, event)

            machine = app.SelecttheTokamakDropDown.Value;

            %% Update values separatrix

            app.equi.config.separatrix.R0 = app.MagneticMajorRadiusRmSpinner.Value;
            app.equi.config.separatrix.Z0 = app.Zmag.Value;
            app.equi.config.separatrix.a = app.a.Value;

            app.equi.config.separatrix.k1 = app.k1Spinner.Value;
            app.equi.config.separatrix.k2 = app.k2Spinner.Value;
            app.equi.config.separatrix.d1 = app.d1Spinner.Value;
            app.equi.config.separatrix.d2 = app.d2Spinner.Value;

            app.equi.config.separatrix.gamma_n_1 = app.gn1Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_n_2 = app.gn2Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_p_1 = app.gp1Spinner.Value*pi/180;
            app.equi.config.separatrix.gamma_p_2 = app.gp2Spinner.Value*pi/180;

            %% Build new Separatrix

            app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);

            %% Update values MHD and Kinetic

            app.equi.config.toroidal_current.Bt = app.B_tTSpinner.Value;
            app.equi.config.toroidal_current.Ip = app.I_pMASpinner.Value*1e6;
            app.equi.config.toroidal_current.alpha_1 = app.J_ta_1Spinner.Value;
            app.equi.config.toroidal_current.alpha_2 = app.J_ta_2Spinner.Value;
            app.equi.config.toroidal_current.beta_0 = app.beta_0Spinner.Value;

            app.equi.config.kinetic.n0 = app.n_01e19Spinner.Value*1e19;
            app.equi.config.kinetic.nsep = app.n_s1e19Spinner.Value*1e19;
            app.equi.config.kinetic.a1 = app.na_1Spinner.Value;
            app.equi.config.kinetic.a2 = app.na_2Spinner.Value;

            %% Plot

            hold(app.PlotField, 'off')
            plot(app.PlotField,app.equi.separatrix.R_sep_target,app.equi.separatrix.Z_sep_target,'-r','LineWidth',1.2)
            hold(app.PlotField, 'on')
            plot(app.PlotField,app.equi.geo.wall.R,app.equi.geo.wall.Z,'-k','LineWidth',1.2)
            axis(app.PlotField,"equal")
            grid(app.PlotField,"on")
            grid(app.PlotField,"minor")
            xlabel(app.PlotField,"R [m]")
            ylabel(app.PlotField,"Z [m]")
            xlim(app.PlotField,[min(app.geo.R) max(app.geo.R)])
            ylim(app.PlotField,[min(app.geo.Z) max(app.geo.Z)])

            %% Update Equilibrium profiles
            if app.Check_GSsolver.Value

                app.equi.separatrix = app.equi.separatrix.build_separatrix(app.equi.config.separatrix,app.equi.geo);
                app.equi.config.GSsolver.Plotting = 0;

                app.equi.config.GSsolver.maxIter = app.IterationSpinner.Value;

                app.equi = app.equi.solve_equilibrium_dimless();
                app.equi = app.equi.equi_pp2();
                app.equi = app.equi.compute_profiles();

                %% Plot
                function_plotfield(app)
                function_plotprofiles(app)

                %% Diagnostics
                app.PickUp = Diag_PickUpCoils();
                app.PickUp = app.PickUp.Upload(1,machine);

                app.FluxLoops = Diag_FluxLoops();
                app.FluxLoops = app.FluxLoops.Upload(1,machine);

                app.SaddleCoils = Diag_SaddleCoils();
                app.SaddleCoils = app.SaddleCoils.Upload(1,machine);

                app.TS = Diag_ThomsonScattering();
                app.TS = app.TS.Upload(1,machine);

                app.IntPol = Diag_InterferometerPolarimeter();
                app.IntPol = app.IntPol.Upload(1,machine);

                app.Bolo = Diag_Bolo();
                app.Bolo = app.Bolo.Upload(1,machine);

                %% Plot Diagnostics
                function_plotdiagnostics(app)

            end

            %% Diagnostics
            app.PickUp = app.PickUp.measure(app.equi);


            %% Plot Diagnostics
            function_plotdiagnostics(app)
            function_Diagnostics_Measurements(app)

            %% Touching Wall
            if app.equi.separatrix.touching_wall == 1
                app.SeparatrixWall.Color = [1 0 0];
            else
                app.SeparatrixWall.Color = [0.1 0.1 0.1];
            end

        end

        % Value changed function: DropDown
        function Callback_UpdatePlot(app, event)
            % value = app.DropDown.Value;

            %% Plot
            function_plotfield(app)
            function_plotprofiles(app)

        end

        % Value changed function: BolometerCheckBox, BotFigureDropDown, 
        % ...and 8 other components
        function Callback_Diagnostic(app, event)

            function_plotdiagnostics(app)
            function_Diagnostics_Measurements(app)


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {658, 658, 658};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {658, 658};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x', 467};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Hyperlink_GitHub = uihyperlink(app.GridLayout2);
            app.Hyperlink_GitHub.URL = 'https://github.com/TokaLab/VirtualLab';
            app.Hyperlink_GitHub.Text = 'GitHub';
            app.Hyperlink_Mathwork_FileExchange = uihyperlink(app.GridLayout2);
            app.Hyperlink_Mathwork_FileExchange.URL = 'https://it.mathworks.com/matlabcentral/fileexchange/182014-tokalab';
            app.Hyperlink_Mathwork_FileExchange.Text = 'FileExhange';
            app.Hyperlink_LinkedIn = uihyperlink(app.GridLayout2);
            app.Hyperlink_LinkedIn.URL = 'https://www.linkedin.com/company/tokalab-fusion/';
            app.Hyperlink_LinkedIn.Text = 'LinkedIn';
            app.UpperElongationLabel_2 = uilabel(app.GridLayout2);
            app.UpperElongationLabel_2.Text = 'Upper Elongation';
            app.BottomElongationLabel_2 = uilabel(app.GridLayout2);
            app.BottomElongationLabel_2.Text = 'Bottom Elongation';
            app.UpperTriangularityLabel = uilabel(app.GridLayout2);
            app.UpperTriangularityLabel.Text = 'Upper Triangularity';
            app.BottomTriangularityLabel = uilabel(app.GridLayout2);
            app.BottomTriangularityLabel.Text = 'Bottom Triangularity';
            app.gammaupleftLabel = uilabel(app.GridLayout2);
            app.gammaupleftLabel.Text = 'gamma up left';
            app.gammauprightLabel = uilabel(app.GridLayout2);
            app.gammauprightLabel.Text = 'gamma up right';
            app.gammabottomleftLabel = uilabel(app.GridLayout2);
            app.gammabottomleftLabel.Text = 'gamma bottom left';
            app.gammabottomrightLabel = uilabel(app.GridLayout2);
            app.gammabottomrightLabel.Text = 'gamma bottom right';
            app.k1SpinnerLabel = uilabel(app.GridLayout2);
            app.k1SpinnerLabel.Visible = 'off';
            app.k1SpinnerLabel.Text = 'k1';
            app.k1Spinner = uispinner(app.GridLayout2);
            app.k1Spinner.Step = 0.1;
            app.k1Spinner.Limits = [0 4];
            app.k1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.k1Spinner.Value = 1.7;
            app.k2SpinnerLabel = uilabel(app.GridLayout2);
            app.k2SpinnerLabel.Visible = 'off';
            app.k2SpinnerLabel.Text = 'k2';
            app.k2Spinner = uispinner(app.GridLayout2);
            app.k2Spinner.Step = 0.1;
            app.k2Spinner.Limits = [0 4];
            app.k2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.k2Spinner.Value = 2;
            app.d1SpinnerLabel = uilabel(app.GridLayout2);
            app.d1SpinnerLabel.Visible = 'off';
            app.d1SpinnerLabel.Text = 'd1';
            app.d1Spinner = uispinner(app.GridLayout2);
            app.d1Spinner.Step = 0.1;
            app.d1Spinner.Limits = [-1 1];
            app.d1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.d1Spinner.Value = 0.5;
            app.d2SpinnerLabel = uilabel(app.GridLayout2);
            app.d2SpinnerLabel.Visible = 'off';
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.GridLayout2);
            app.d2Spinner.Step = 0.1;
            app.d2Spinner.Limits = [-1 1];
            app.d2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.d2Spinner.Value = 0.5;
            app.gn1SpinnerLabel = uilabel(app.GridLayout2);
            app.gn1SpinnerLabel.Visible = 'off';
            app.gn1SpinnerLabel.Text = 'gn1';
            app.gn1Spinner = uispinner(app.GridLayout2);
            app.gn1Spinner.Limits = [0 90];
            app.gn1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gn2SpinnerLabel = uilabel(app.GridLayout2);
            app.gn2SpinnerLabel.Visible = 'off';
            app.gn2SpinnerLabel.Text = 'gn2';
            app.gn2Spinner = uispinner(app.GridLayout2);
            app.gn2Spinner.Step = 0.1;
            app.gn2Spinner.Limits = [0 90];
            app.gn2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gn2Spinner.Value = 60;
            app.gp1SpinnerLabel = uilabel(app.GridLayout2);
            app.gp1SpinnerLabel.Visible = 'off';
            app.gp1SpinnerLabel.Text = 'gp1';
            app.gp1Spinner = uispinner(app.GridLayout2);
            app.gp1Spinner.Step = 0.1;
            app.gp1Spinner.Limits = [0 90];
            app.gp1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gp2SpinnerLabel = uilabel(app.GridLayout2);
            app.gp2SpinnerLabel.Visible = 'off';
            app.gp2SpinnerLabel.Text = 'gp2';
            app.gp2Spinner = uispinner(app.GridLayout2);
            app.gp2Spinner.Step = 0.1;
            app.gp2Spinner.Limits = [0 90];
            app.gp2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.gp2Spinner.Value = 30;
            app.TargetSeparatrixParametersLabel = uilabel(app.GridLayout2);
            app.TargetSeparatrixParametersLabel.Text = 'Target Separatrix - Parameters';
            app.MagneticMajorRadiusRmSpinnerLabel = uilabel(app.GridLayout2);
            app.MagneticMajorRadiusRmSpinnerLabel.Text = 'Magnetic Major Radius R [m]';
            app.MagneticMajorRadiusRmSpinner = uispinner(app.GridLayout2);
            app.MagneticMajorRadiusRmSpinner.Step = 0.1;
            app.MagneticMajorRadiusRmSpinner.Limits = [0 Inf];
            app.MagneticMajorRadiusRmSpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.MagneticMajorRadiusRmSpinner.Value = 6;
            app.MagneticAxisZpositionmSpinnerLabel = uilabel(app.GridLayout2);
            app.MagneticAxisZpositionmSpinnerLabel.Text = 'Magnetic Axis - Z position [m]';
            app.Zmag = uispinner(app.GridLayout2);
            app.Zmag.Step = 0.1;
            app.Zmag.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.MinorRadiusamSpinnerLabel = uilabel(app.GridLayout2);
            app.MinorRadiusamSpinnerLabel.Text = 'Minor Radius a [m]';
            app.a = uispinner(app.GridLayout2);
            app.a.Step = 0.1;
            app.a.Limits = [0 Inf];
            app.a.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.a.Value = 2;
            app.Image_TokaLab_banner = uiimage(app.GridLayout2);
            app.Image_TokaLab_banner.ImageSource = fullfile(pathToMLAPP, 'apps', 'images', 'TokaLab_banner.png');
            app.SelecttheTokamakDropDownLabel = uilabel(app.GridLayout2);
            app.SelecttheTokamakDropDownLabel.Text = 'Select the Tokamak';
            app.SelecttheTokamakDropDown = uidropdown(app.GridLayout2);
            app.SelecttheTokamakDropDown.Items = {'TokaLab', 'TCV-like', 'DTT-like', 'JET-like'};
            app.SelecttheTokamakDropDown.ValueChangedFcn = createCallbackFcn(app, @Callback_Initialise, true);
            app.SelecttheTokamakDropDown.Value = 'TokaLab';
            app.Button_Initialise = uibutton(app.GridLayout2, 'push');
            app.Button_Initialise.ButtonPushedFcn = createCallbackFcn(app, @Callback_Initialise, true);
            app.Button_Initialise.Text = 'Initialise';
            app.Button_Update = uibutton(app.GridLayout2, 'push');
            app.Button_Update.ButtonPushedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.Button_Update.Text = 'Update';
            app.TokaLabappalphaversionLabel = uilabel(app.GridLayout2);
            app.TokaLabappalphaversionLabel.Text = 'TokaLab app - alpha version';
            app.ContactusforbugslinkbelowLabel = uilabel(app.GridLayout2);
            app.ContactusforbugslinkbelowLabel.Text = 'Contact us for bugs (link below)';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'apps', 'images', 'MATLAB_banner_empty.png');
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.PlotField = uiaxes(app.GridLayout3);
            title(app.PlotField, 'GS Solution - ')
            xlabel(app.PlotField, 'R [m]')
            ylabel(app.PlotField, 'Z [m]')
            zlabel(app.PlotField, 'Z')
            app.B_tTSpinnerLabel = uilabel(app.GridLayout3);
            app.B_tTSpinnerLabel.Text = 'B_t [T]';
            app.B_tTSpinner = uispinner(app.GridLayout3);
            app.B_tTSpinner.Step = 0.1;
            app.B_tTSpinner.Limits = [0 Inf];
            app.B_tTSpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.B_tTSpinner.Value = 6;
            app.I_pMASpinnerLabel = uilabel(app.GridLayout3);
            app.I_pMASpinnerLabel.Text = 'I_p [MA]';
            app.I_pMASpinner = uispinner(app.GridLayout3);
            app.I_pMASpinner.Step = 0.1;
            app.I_pMASpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.I_pMASpinner.Value = -12;
            app.beta_0SpinnerLabel = uilabel(app.GridLayout3);
            app.beta_0SpinnerLabel.Text = 'beta_0';
            app.beta_0Spinner = uispinner(app.GridLayout3);
            app.beta_0Spinner.Step = 0.1;
            app.beta_0Spinner.Limits = [0 Inf];
            app.beta_0Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.beta_0Spinner.Value = 0.5;
            app.J_ta_1SpinnerLabel = uilabel(app.GridLayout3);
            app.J_ta_1SpinnerLabel.Text = 'J_t a_1';
            app.J_ta_1Spinner = uispinner(app.GridLayout3);
            app.J_ta_1Spinner.Step = 0.1;
            app.J_ta_1Spinner.Limits = [0 Inf];
            app.J_ta_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.J_ta_1Spinner.Value = 2;
            app.J_ta_2SpinnerLabel = uilabel(app.GridLayout3);
            app.J_ta_2SpinnerLabel.Text = 'J_t a_2';
            app.J_ta_2Spinner = uispinner(app.GridLayout3);
            app.J_ta_2Spinner.Step = 0.1;
            app.J_ta_2Spinner.Limits = [0 Inf];
            app.J_ta_2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.J_ta_2Spinner.Value = 2;
            app.n_01e19SpinnerLabel = uilabel(app.GridLayout3);
            app.n_01e19SpinnerLabel.Text = 'n_0 [1e19]';
            app.n_01e19Spinner = uispinner(app.GridLayout3);
            app.n_01e19Spinner.Step = 0.1;
            app.n_01e19Spinner.Limits = [0 Inf];
            app.n_01e19Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.n_01e19Spinner.Value = 10;
            app.n_s1e19SpinnerLabel = uilabel(app.GridLayout3);
            app.n_s1e19SpinnerLabel.Text = 'n_s [1e19]';
            app.n_s1e19Spinner = uispinner(app.GridLayout3);
            app.n_s1e19Spinner.Step = 0.001;
            app.n_s1e19Spinner.Limits = [0 Inf];
            app.n_s1e19Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.n_s1e19Spinner.Value = 0.01;
            app.na_1SpinnerLabel = uilabel(app.GridLayout3);
            app.na_1SpinnerLabel.Text = 'n a_1';
            app.na_1Spinner = uispinner(app.GridLayout3);
            app.na_1Spinner.Step = 0.1;
            app.na_1Spinner.Limits = [0 Inf];
            app.na_1Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.na_1Spinner.Value = 2;
            app.na_2SpinnerLabel = uilabel(app.GridLayout3);
            app.na_2SpinnerLabel.Text = 'n a_2';
            app.na_2Spinner = uispinner(app.GridLayout3);
            app.na_2Spinner.Step = 0.1;
            app.na_2Spinner.Limits = [0 Inf];
            app.na_2Spinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.na_2Spinner.Value = 3;
            app.IterationSpinnerLabel = uilabel(app.GridLayout3);
            app.IterationSpinnerLabel.Text = 'Iteration';
            app.IterationSpinner = uispinner(app.GridLayout3);
            app.IterationSpinner.Limits = [1 100];
            app.IterationSpinner.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.IterationSpinner.Value = 1;
            app.Check_GSsolver = uicheckbox(app.GridLayout3);
            app.Check_GSsolver.ValueChangedFcn = createCallbackFcn(app, @Callback_Update, true);
            app.Check_GSsolver.Text = 'GS solver';
            app.Check_GSsolver.Value = true;
            app.PlasmaandProfileParametersLabel = uilabel(app.GridLayout3);
            app.PlasmaandProfileParametersLabel.Text = 'Plasma and Profile Parameters';
            app.GSsolverparametersLabel = uilabel(app.GridLayout3);
            app.GSsolverparametersLabel.Text = 'GS solver parameters';
            app.DropDownLabel = uilabel(app.GridLayout3);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'psi [Wb/rad]', 'psi_n [arb. units]', 'Psi [Wb]', 'Br [T]', 'Bt [T]', 'Bz [T]', 'Jr [A/m^2]', 'Jt [A/m^2]', 'Jz [A/m^2]', 'pe [Pa]', 'pi [Pa]', 'ne [m^{-3}]', 'ni [m^{-3}]', 'Te [eV]', 'Ti [eV]'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlot, true);
            app.DropDown.Value = 'pe [Pa]';
            app.SeparatrixOutsideVesselLampLabel = uilabel(app.GridLayout3);
            app.SeparatrixOutsideVesselLampLabel.Text = 'Separatrix Outside Vessel';
            app.SeparatrixWall = uilamp(app.GridLayout3);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.ProfilesTab = uitab(app.TabGroup2);
            app.ProfilesTab.Title = 'Profiles';
            app.GridLayout5 = uigridlayout(app.ProfilesTab);
            app.PlotProfile1 = uiaxes(app.GridLayout5);
            title(app.PlotProfile1, 'Density Profiles')
            xlabel(app.PlotProfile1, '\psi_n [arb. units]')
            ylabel(app.PlotProfile1, 'n [m^{-3}]')
            zlabel(app.PlotProfile1, 'Z')
            app.PlotProfile2 = uiaxes(app.GridLayout5);
            title(app.PlotProfile2, 'Temperature Profiles')
            xlabel(app.PlotProfile2, '\psi_n [arb.units]')
            ylabel(app.PlotProfile2, 'T [eV]')
            zlabel(app.PlotProfile2, 'Z')
            app.PlotProfile3 = uiaxes(app.GridLayout5);
            title(app.PlotProfile3, 'Pressure Profiles')
            xlabel(app.PlotProfile3, '\psi_n [arb.units]')
            ylabel(app.PlotProfile3, 'p [Pa]')
            zlabel(app.PlotProfile3, 'Z')
            app.DiagnosticsTab = uitab(app.TabGroup2);
            app.DiagnosticsTab.Title = 'Diagnostics';
            app.GridLayout6 = uigridlayout(app.DiagnosticsTab);
            app.DiagnosticGeometry = uiaxes(app.GridLayout6);
            title(app.DiagnosticGeometry, 'Diagnostic Geometry')
            xlabel(app.DiagnosticGeometry, 'R [m]')
            ylabel(app.DiagnosticGeometry, 'Z [m]')
            zlabel(app.DiagnosticGeometry, 'Z')
            app.PickUpCoilsCheckBox = uicheckbox(app.GridLayout6);
            app.PickUpCoilsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.PickUpCoilsCheckBox.Text = 'Pick Up Coils';
            app.PickUpCoilsCheckBox.Value = true;
            app.SaddleCoilsCheckBox = uicheckbox(app.GridLayout6);
            app.SaddleCoilsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.SaddleCoilsCheckBox.Text = 'Saddle Coils';
            app.SaddleCoilsCheckBox.Value = true;
            app.FluxLoopsCheckBox = uicheckbox(app.GridLayout6);
            app.FluxLoopsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.FluxLoopsCheckBox.Text = 'Flux Loops';
            app.FluxLoopsCheckBox.Value = true;
            app.ThomsonScatteringCheckBox = uicheckbox(app.GridLayout6);
            app.ThomsonScatteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.ThomsonScatteringCheckBox.Text = 'Thomson Scattering';
            app.ThomsonScatteringCheckBox.Value = true;
            app.InterferometerPolarimeterCheckBox = uicheckbox(app.GridLayout6);
            app.InterferometerPolarimeterCheckBox.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.InterferometerPolarimeterCheckBox.Text = 'Interferometer-Polarimeter';
            app.InterferometerPolarimeterCheckBox.Value = true;
            app.BolometerCheckBox = uicheckbox(app.GridLayout6);
            app.BolometerCheckBox.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.BolometerCheckBox.Text = 'Bolometer';
            app.MeasurementsTab = uitab(app.TabGroup2);
            app.MeasurementsTab.Title = 'Measurements';
            app.GridLayout7 = uigridlayout(app.MeasurementsTab);
            app.FigureMeasTop = uiaxes(app.GridLayout7);
            title(app.FigureMeasTop, 'Title')
            xlabel(app.FigureMeasTop, 'X')
            ylabel(app.FigureMeasTop, 'Y')
            zlabel(app.FigureMeasTop, 'Z')
            app.FigureMeasBot = uiaxes(app.GridLayout7);
            title(app.FigureMeasBot, 'Title')
            xlabel(app.FigureMeasBot, 'X')
            ylabel(app.FigureMeasBot, 'Y')
            zlabel(app.FigureMeasBot, 'Z')
            app.TopFigureDropDownLabel = uilabel(app.GridLayout7);
            app.TopFigureDropDownLabel.Text = 'Top Figure';
            app.TopFigureDropDown = uidropdown(app.GridLayout7);
            app.TopFigureDropDown.Items = {'Pick Up Coils', 'Saddle Loops', 'Flux Loops', 'Interferometer', 'Polarimeter (Faraday Rotation)', 'Polarimeter (Cotton Mouton)', 'Thomson Scattering (Density)', 'Thomson Scattering (Temperature)', 'Bolometry'};
            app.TopFigureDropDown.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.TopFigureDropDown.Value = 'Pick Up Coils';
            app.BottomFigureDropDownLabel = uilabel(app.GridLayout7);
            app.BottomFigureDropDownLabel.Text = 'Bottom Figure';
            app.BotFigureDropDown = uidropdown(app.GridLayout7);
            app.BotFigureDropDown.Items = {'Pick Up Coils', 'Saddle Loops', 'Flux Loops', 'Interferometer', 'Polarimeter (Faraday Rotation)', 'Polarimeter (Cotton Mouton)', 'Thomson Scattering (Density)', 'Thomson Scattering (Temperature)', 'Bolometry'};
            app.BotFigureDropDown.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.BotFigureDropDown.Value = 'Pick Up Coils';
            app.DiagnosticNoiseLabel = uilabel(app.GridLayout7);
            app.DiagnosticNoiseLabel.Text = 'Diagnostic Noise [%]';
            app.DiagnosticNoiseSlider_top = uislider(app.GridLayout7);
            app.DiagnosticNoiseSlider_top.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            app.DiagnosticNoiseSlider_2Label = uilabel(app.GridLayout7);
            app.DiagnosticNoiseSlider_2Label.Text = 'Diagnostic Noise [%]';
            app.DiagnosticNoiseSlider_bot = uislider(app.GridLayout7);
            app.DiagnosticNoiseSlider_bot.ValueChangedFcn = createCallbackFcn(app, @Callback_Diagnostic, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TokaLab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Callback_StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end